classdef UniversalTaucPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TaucPlotFittingUIFigure      matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        IACode                       matlab.ui.control.Label
        IABandGapLabel               matlab.ui.control.Label
        DACode                       matlab.ui.control.Label
        DABandGapLabel               matlab.ui.control.Label
        LoadReflectanceDataButton    matlab.ui.control.Button
        StartFitButton               matlab.ui.control.Button
        LoadCreateError              matlab.ui.control.Label
        LoadAbsDataButton            matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxes_1                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        GoodnessofFitFOMDCodesLabel  matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        UpperFitLimitEditField       matlab.ui.control.NumericEditField
        UpperFitLimitEditFieldLabel  matlab.ui.control.Label
        LowerFitLimitEditField       matlab.ui.control.NumericEditField
        LowerFitLimitEditFieldLabel  matlab.ui.control.Label
        AbsorptionCutoffLimitsLabel  matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SegmentationButton           matlab.ui.control.RadioButton
        MARSButton                   matlab.ui.control.RadioButton
        SNGButton                    matlab.ui.control.RadioButton
        FittingMethodLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        Wavelength % Holds x data from imported csv (wavelength)
        Absorption % Holds y data from imported csv (absorption intensities)
        Shade % Holds rectangle graphic objects that are shaded
        ShadeArea % Holds x1 and x2 positions of both rectangles
        XMin % Holds minimum x data value
        XMax % Holds maximum x data value
        YMin % Holds minimum y data value
        YMax % Holds maximum y data value
        UpperFit % Holds upper fit line x value
        LowerFit % Holds lower fit line x value
        ROILine % Holds graphical (ROI) line objects
        left_line_index % Holds the left index value of the point ROI line sits on
        right_line_index % Holds the right index value of the point ROI line sits on
        OriginalAbs % Holds the original absorption data
        absp % Holds line object of original abs data
        smoothed % Holds line object of smoothed abs data
    end

    methods (Access = private)

        function clearProperties(app)
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);

            app.Wavelength = [];
            app.Absorption = [];

            app.DABandGapLabel.Text = "DA Bandgap Energy (eV): ";
            app.IABandGapLabel.Text = "IA Bandgap Energy (eV): ";
            app.DACode.Text = "DA FOMD Code(s): ";
            app.IACode.Text = "IA FOMD Code(s): ";

%             app.SmoothYDataSwitch.Value = "Off";
            %             app.LoadCreateError.Text = "";
        end

        function [linfitd,fomd,data] = runuvvis(app,data,inputvars)

            % Create empty maps to populate
            pfomd = containers.Map;
            plinfitd = containers.Map;

            % This cycles through analysis types (DA & IA) and creates maps for each populated by fitresult
            for bgtyp = inputvars('analysis_types')

                % Create nested maps for each type
                pfomd(bgtyp{1,1})=containers.Map;
                plinfitd(bgtyp{1,1})=containers.Map;

                % Check for nan/missing values
                if any(ismissing(data(bgtyp{1,1})),2)
                    code = pfomd(bgtyp{1,1});
                    code('bgcode_0') = 8;
                    continue;
                end

                % Implementation of second round of filtering in cases where peaks exist is currently unsupported
                if inputvars('use_absderivs_forpeaks')
                    if identifypeaks(app,data,'abs',inputvars('abs_minallowedslope'),inputvars('max_absolute_2ndderiv'))
                        code = pfomd(bgtyp);
                        code('bgcode_0') = 7;
                        continue;
                    end
                end

                try
                    [plinfitd(bgtyp{1,1}),pfomd(bgtyp{1,1})] = fitresult(app,data,bgtyp,inputvars('maxbgspersmp'),...
                        inputvars('num_knots'),inputvars('tol'),inputvars('min_allowedslope'),...
                        inputvars('min_bgTP_diff'),inputvars('min_bkgrdslope'),...
                        inputvars('min_bgbkgrdslopediff'),inputvars('min_finseglength'),...
                        inputvars('min_bgTP_finseg_diff'),...
                        inputvars('min_bgfinalseglength'),inputvars('max_merge_differentialTP'),...
                        inputvars('merge_linsegslopediff_percent'),inputvars('maxtol'),...
                        inputvars('min_knotdist'),'increasing',"off");
                catch e
                    disp(e);
                    return
                end
            end

            fomd = containers.Map;
            linfitd = containers.Map;

            % Populate maps for each analysis type and fomd/linfitd
            for bgtyp = keys(pfomd)
                for k = keys(pfomd(bgtyp{1,1}))
                    val = pfomd(bgtyp{1,1});
                    fomd([bgtyp{1,1},'_',k{1,1}]) = val(k{1,1});
                end
            end

            for bgtyp = keys(plinfitd)
                for k = keys(plinfitd(bgtyp{1,1}))
                    val = plinfitd(bgtyp{1,1});
                    linfitd([bgtyp{1,1},'_',k{1,1}]) = val(k{1,1});
                end
            end

            for k = keys(linfitd)
                cells = split(k,'_');
                strs = string(cells);

                if strcmp(strs(end),'knots') || strcmp(strs(end), 'slopes')
                    for idx = 1:length(linfitd(k{1,1}))
                        val = linfitd(k{1,1});
                        linfitd([k{1,1},'_',num2str(idx-1)]) = val(idx);
                    end
                end
            end

            % Remove nonscalar value entries
            for k = keys(linfitd)
                if ~isscalar(linfitd(k{1,1}))
                    remove(linfitd, k{1,1});
                end
            end

        end

        function [linfitd, fomd] = fitresult(app, data, bgtyp, max_numbgs, num_knots, tol, min_allowedslope, min_bgTP_diff, min_bkgrdslope, ...
                min_bgbkgrdslopediff, min_finseglength, min_bgTP_finseg_diff, min_bgfinalseglength, max_merge_differentialTP, merge_linsegslopediff_percent, ...
                maxtol, min_knotdist, xorder, dispresult)

            % This function creates data for each bg type and interacts with fitting, residual measurement functions to identify the best linear piecewise parameters which are then sent to calc_bandgap for bandgap calculations.
            %             datarray = table2array(data(bgtyp{1,1}));
            %             energy = table2array(data('hv'));
            datarray = data(bgtyp{1,1});
            energy = data('hv');

            if min(datarray) < 0.03
                yoffset = -min(datarray) + 0.03;
            else
                yoffset = 0;
            end

            new_offset = datarray + yoffset;

            % Typically, x is a local solution to the problem when exitflag is positive
            for maxiter = [1000,30000]
                %                 [x,fval,exitflag,output] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                [x,~,~,exitflag,~,~,~] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                %                 [x,fval,exitflag,output,lambda,grad,hessian] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                %                 [x,R,J,CovB,MSE,ErrorModelInfo] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                if exitflag > 0
                    break
                end
            end

            if exitflag <= 0
                maxiter = 2000;
                for i = log10(tol):log10(maxtol)+1
                    newtol = 10^i;
                    %                     [x,fval,exitflag,output] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                    [x,~,~,exitflag,~,~,~] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), newtol);
                    %                     [x,fval,exitflag,output,lambda,grad,hessian] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), tol);
                    %                     [beta,R,J,CovB,MSE,ErrorModelInfo] = linearfit(app, energy, new_offset, num_knots, min_knotdist, xorder, containers.Map({'maxiter', 'disp'}, [maxiter,dispresult]), newtol);
                    if exitflag > 0
                        break
                    end
                end
            end

            if exitflag <= 0
                linfitd = containers.Map;
                fomd = containers.Map('bgcode_0',9);
            else
                % Set temporary parameters to solution vector x from linearfit function (lsqcurvefit)
                tempparams = x;
                tempparams(1) = tempparams(1) - yoffset;
                tempparams = mergelinsegs(app, tempparams, num_knots, max_merge_differentialTP, merge_linsegslopediff_percent);

                try
                    [linfitd,fomd] = calc_bandgap(app, tempparams, numel(tempparams)/2, max_numbgs, min_allowedslope, min_bgTP_diff, min_bkgrdslope, min_bgbkgrdslopediff, min_finseglength, min_bgTP_finseg_diff, min_bgfinalseglength, bgtyp);
                catch e
                    disp(e);
                    return
                end

                data(string([bgtyp{1,1},'_linfit'])) = linpiecewise(app,[linfitd('y0'); linfitd('knots'); linfitd('slopes')], energy);
            end
        end

        %         function    [x,fval,exitflag,output,lambda,grad,hessian] = linearfit(app, xdata, ydata, num_knots, min_knotdist, xorder, options, tol)
        %         function    [beta,R,J,CovB,MSE,ErrorModelInfo] = linearfit(app, xdata, ydata, num_knots, min_knotdist, xorder, options, tol)
        function [x,resnorm,residual,exitflag,output,lambda, jacobian] = linearfit(app, xdata, ydata, num_knots, min_knotdist, xorder, options, tol)
            % xorder: if loc in data represents smaller value of energy to higher value of energy then xorder is increasing otherwise decreasing
            % first constraint in cons is to ensure minimum knot distance min_knotdist. Second equality constraint indicates that the first and the last knot are fixed to the first and last values of x
            % Initially knot locations are evenly spread out between xvalues of interest and all slopes are set to zero. tolerance is the tolerance for convergence of linear properties.
            if xorder == "increasing"
                sign = 1;
            else
                sign = -1;
            end

            init_x = xdata(1):(xdata(end)-xdata(1))/(num_knots-1):xdata(end);
            init_x = init_x';

            if numel(init_x)~=num_knots
                init_x = [init_x;xdata(end)];
            else
                init_x(end)=xdata(end);
            end

            for i = 1:numel(init_x)
                [~,init_idxs(i,:)] = min(abs(xdata-init_x(i)));
            end

            for i = 1:length(init_idxs)-1
                init_slopes(i,:) = (ydata(init_idxs(i+1))-ydata(init_idxs(i)))/(xdata(init_idxs(i+1))-xdata(init_idxs(i)));
            end

            init_params = [ydata(1); init_x; init_slopes];

            % fitnlm for example or lsqnonlin
            % put a wrapper function that calculates the residuals

            % BEST OPTION SO FAR
            %             opts = optimoptions("lsqcurvefit","Algorithm","levenberg-marquardt", "FunctionTolerance",1e-16,"MaxIterations",str2double(options('maxiter')),"Display",options('disp'),"StepTolerance",1.5e-8,"MaxFunctionEvaluations",6e4);
            opts = optimoptions("lsqcurvefit","Algorithm","levenberg-marquardt", "FunctionTolerance",tol,"MaxIterations",str2double(options('maxiter')),"Display",options('disp'),"StepTolerance",tol);
            [x,resnorm,residual,exitflag,output,lambda,jacobian] = lsqcurvefit(@(params,x)linpiecewise(app,params,x), init_params, xdata, ydata, [], [], opts);

            % BAD
            %             tst = statset("MaxIter",str2double(options('maxiter')),"TolFun",tol,"Display",options('disp'));
            %             mdl = fitnlm(xdata,ydata,@residuals,init_params,"Options",tst);
            %             [beta,R,J,CovB,MSE,ErrorModelInfo] = nlinfit(xdata,ydata,@(params,xdata)newFunc(app,params,xdata,ydata),init_params,tst);

            % THIS RESOLVES TO MINIMUM. IA BG STILL NOTICEABLY OFF
            %             opts = optimoptions("lsqnonlin","Algorithm","levenberg-marquardt", "FunctionTolerance",1e-18,"MaxIterations",str2double(options('maxiter')),"Display",options('disp'),"StepTolerance",1e-25);
            %             opts = optimoptions("lsqnonlin","Algorithm","levenberg-marquardt","FunctionTolerance",tol,"MaxIterations",str2double(options('maxiter')),"Display",options('disp'),"MaxFunctionEvaluations",6e4,"CheckGradients",true,"StepTolerance",1e-25);
            %             [x,resnorm,residual,exitflag,output,lambda,jacobian] = lsqnonlin(@(params)linpiecewise(app, params, xdata), init_params, [], [], opts);

            % MAYBE
            %             opts = optimoptions("fmincon","Algorithm","sqp","FunctionTolerance",tol,"MaxIterations", str2double(options('maxiter')),"Display",options('disp'));%,"ConstraintTolerance",2.7e-1);
            %             [x,fval,exitflag,output,lambda,grad,hessian] = fmincon(@(params)residuals(app,params,xdata,ydata), init_params, [], [], [], [], [], [], @(params)nonlcon(app,params,xdata,num_knots,sign,min_knotdist), opts);

            %             opts = optimset("MaxIter",str2double(options('maxiter')),"Display",options('disp'),"TolFun",tol,"TolX",tol,'MaxFunEvals',2e4);
            %             [x,fval,exitflag,output] = fminsearch(@(params)residuals(app,params,xdata,ydata), init_params,opts);
        end

        function params = mergelinsegs(~, params, num_knots, max_merge_differentialTP, merge_linsegslopediff_percent)

            knots = params(2:num_knots+1);
            slopes = params(num_knots+2:(2*num_knots));

            % Turn merge_linsegslopediff_percent into fraction
            if merge_linsegslopediff_percent >= 1
                merge_linsegslopediff_percent = merge_linsegslopediff_percent/100;
            end

            while true
                num_merges = 0;
                j = 1;
                loc = 1;

                newslopes = zeros(size(slopes));
                newknots = zeros(size(knots));

                newslopes(1) = slopes(1);
                newknots(1:2) = knots(1:2);

                while loc < size(slopes,1)
                    medianslope = (newslopes(j)*(newknots(j+1) - newknots(j)) + slopes(loc+1)*(knots(loc+2) - knots(loc+1)))/(knots(loc+2)-newknots(j));
                    differentialTPdiff = (medianslope - newslopes(j))*(newknots(j+1)-newknots(j));
                    TPdiff = newslopes(j)*(newknots(j+1)-newknots(j));
                    TPdiffn = slopes(loc+1)*(knots(loc+2)-knots(loc+1));
                    if abs(differentialTPdiff) < min(max_merge_differentialTP,merge_linsegslopediff_percent*(TPdiff+TPdiffn))
                        newslopes(j) = medianslope;
                        slopes(loc) = medianslope;
                        slopes(loc+1) = medianslope;
                        newknots(j+1) = knots(loc+2);
                        loc = loc+1;
                        num_merges = num_merges + 1;
                    else
                        j = j+1;
                        loc = loc+1;
                        newslopes(j) = slopes(loc);
                        newknots(j) = knots(loc);
                        newknots(j+1) = knots(loc+1);
                    end
                end

                newslopes(j+1:end) = [];
                newknots(j+2:end) = [];
                slopes = newslopes;
                knots = newknots;

                if num_merges == 0
                    break
                end
            end

            num_knots = j+2;
            params = [params(1,1);knots;slopes];
        end

        function [linfitd,fomd] = calc_bandgap(app,params,num_knots,max_numbgs,min_allowedslope,min_bgTP_diff,min_bkgrdslope,min_bgbkgrdslopediff,min_finseglength,min_bgTP_finseg_diff,min_bgfinalseglength,bgtyp)

            knots = params(2:num_knots+1);
            slopes = params(num_knots+2:2*num_knots);
            num_slopes = size(slopes,1);

            bgknots_lower = [];
            bkgrdknots_lower = [];
            bg = [];
            abs_expl = [];
            bgcode = [];

            if num_slopes == 1
                uialert(app.TaucPlotFittingUIFigure,"Only one slope was found. Please adjust fit limits and try again", "One Slope Only","Icon","error");
                return
            end

            if (knots(num_slopes) - knots(num_slopes-1)) >= min_finseglength
                num_segments = num_slopes; %MARK
            else
                num_segments = num_slopes - 1;
            end

            tot_segs = num_segments;

            for i = 1:tot_segs - 1
                if slopes(i) < min_allowedslope
                    num_segments = i;
                    break
                end
            end

            if num_segments == tot_segs
                for i = 2:num_segments - 1
                    if slopes(i) > slopes(i-1) && slopes(i) > 0 && slopes(i-1) > min_bkgrdslope
                        TPdiff = (knots(i+1) - knots(i))*slopes(i);

                        if TPdiff >= min_bgTP_diff
                            if slopes(i) > slopes(i+1)
                                bgknots_lower(end+1) = i;
                                abs_expl(end + 1) = TPdiff;
                                bgcode(end+1) = 0;
                                %MARK on i=end (5)
                            end
                        end
                    end
                end

                if numel(bgknots_lower) == 0
                    j = num_segments; %-1; THIS WAS ONE GITHUB FIX

                    if slopes(j) > slopes(j-1) && slopes(j) > 0 && slopes(j-1) > min_bkgrdslope
                        TPdiff = (knots(j+1) - knots(j))*slopes(j);
                        if TPdiff >= min_bgTP_finseg_diff && knots(j+1) - knots(j) > min_bgfinalseglength
                            bgknots_lower(end+1) = j;
                            abs_expl(end + 1) = TPdiff;
                            bgcode(end+1) = 6;
                        end
                    end
                end

                if numel(bgknots_lower) ~= 0
                    for i=1:size(bgknots_lower,1)
                        if i==1
                            low_limit = -1; %MARK
                        else
                            low_limit = bgknots_lower(i-1);
                        end

                        if bgknots_lower(i) - 1 == low_limit
                            delete(bgknots_lower(i:end));
                            delete(abs_expl(i:end));
                            delete(bgcode(i:end));
                            break;
                        end

                        % A band gap segments bkgrnd segment cannot be previous band gap segment
                        for loc = bgknots_lower(i)-1:-1:low_limit+2 %IS THIS ALWAYS CORRECT?? Might have to implement when low_limit > 0 or < 0
                            if slopes(loc) < slopes(loc+1) && slopes(loc) > min_bkgrdslope
                                if loc == low_limit + 2 %SHOULD THIS BE + 1 OR + 2
                                    bkgrdknots_lower(end+1) = loc;
                                    % This block gets executed when the bkgrd segment is just to the right of the previous band gap segment
                                    break
                                else
                                    continue %MARK
                                end
                            elseif loc ~= bgknots_lower(i) - 1
                                bkgrdknots_lower(end+1) = loc + 1; %MARK
                                break
                            else
                                temp = size(bgknots_lower,1);
                                delete(bgknots_lower(i:end));
                                delete(abs_expl(i:end));
                                delete(bgcode(i:end));

                                i = temp;

                                %This block should never get executed because any bgknot will have corresponding bkgrdknot except
                                %when the previous segment is also a band gap segment taken care of bgknots_lower[i]-1==low_limit block above
                            end
                        end
                    end
                end

                if numel(bgknots_lower) ~= 0
                    if ~(numel(bgcode) == 1 && bgcode(1) == 1)
                        if ~(((numel(abs_expl) == numel(bgknots_lower) && (numel(bgknots_lower) == numel(bkgrdknots_lower))) && numel(bgcode(bgcode ~= 1)) == numel(bkgrdknots_lower)))
                            error('abs_expl,bgknots_lower,bgcodes and bkgrdknots_lower do not have the same size');
                        end
                    end

                    for i = 1:size(bkgrdknots_lower,1)
                        if (slopes(bgknots_lower(i)) - slopes(bkgrdknots_lower(i))) < min_bgbkgrdslopediff

                            bgknots_lower(i) = -1000;
                            bkgrdknots_lower(i) = -1000;
                            abs_expl(i) = -1000;

                            bgcode(end+1) = 4;
                        end
                    end

                    % Now erase marked values from variables if there are any
                    bgknots_lower = bgknots_lower(bgknots_lower ~= -1000);
                    bkgrdknots_lower = bkgrdknots_lower(bkgrdknots_lower ~= -1000);
                    abs_expl = abs_expl(abs_expl ~= -1000);

                    if numel(bgknots_lower) == 0
                        bgknots_lower = NaN;
                        bkgrdknots_lower = NaN;
                        bg = NaN;
                        abs_expl = NaN;

                        bgcode(end+1) = 5;
                    else
                        % Main block of code for calculating Band Gap Energy %MARK
                        for i = 1:size(bgknots_lower,1)
                            y1 = linpiecewise(app,params,knots(bgknots_lower,i));
                            y2 = linpiecewise(app,params,knots(bkgrdknots_lower,i));

                            m1 = slopes(bgknots_lower(i));
                            m2 = slopes(bkgrdknots_lower(i));

                            x1 = knots(bgknots_lower(i));
                            x2 = knots(bkgrdknots_lower(i));

                            bg(end+1) = (y1-y2-(m1*x1-m2*x2))/(m2-m1);
                        end
                    end
                else
                    bgknots_lower = NaN;
                    bkgrdknots_lower = NaN;
                    bg = NaN;
                    abs_expl = NaN;
                    bgcode(end+1) = 3;
                end

            elseif num_segments ~= tot_segs
                if numel(bgcode) == 0
                    bgknots_lower = NaN;
                    bkgrdknots_lower = NaN;
                    bg = NaN;
                    abs_expl = NaN;
                    bgcode(end+1) = 1;
                end
            end

            fomd = containers.Map('KeyType','char','ValueType','any');
            for lstk = {'bgknots_lower','bkgrdknots_lower', 'bg','abs_expl','bgcode'}
                for idx = 1:round(min(length(bg),max_numbgs))
                    keys = string([lstk{1,1},'_',num2str(idx-1)]);
                    vals = eval(lstk{1,1});
                    fomd(keys) = vals;
                end
            end

            linfitd = containers.Map('KeyType','char','ValueType','any');
            for lstk = {'bgknots_lower','bkgrdknots_lower'}
                for idx = 1:min(length(bg),max_numbgs)
                    keys = string([lstk{1,1},'_',num2str(idx-1)]);
                    vals = eval(lstk{1,1});
                    linfitd(keys) = vals;
                end
            end

            % Add on new key-value pairs after loop
            linfitd('knots') = knots;
            linfitd('slopes') = slopes;
            linfitd('y0') = params(1);


            if ~all(isnan(abs_expl))
                for idx = 1:min(length(bg),max_numbgs)
                    if ~isnan(fomd(string(['abs_expl','_',num2str(idx-1)])))
                        [~,x] = max(fomd(string(['abs_expl','_',num2str(idx-1)]))); %MARK
                    end
                end
                %MARK
                fomd('bg_repr') = fomd(['bg_',num2str(x-1)]);
                fomd('bgslope_repr') = slopes(bgknots_lower(x));
                fomd('bkgrdslope_repr') = slopes(bkgrdknots_lower(x));
                fomd('bgcode_repr') = fomd(['bgcode_',num2str(x-1)]);
            else
                fomd('bg_repr') = nan;
                fomd('bgslope_repr') = nan;
                fomd('bkgrdslope_repr') = nan;
                fomd('bgcode_repr') = nan;
            end

            if length(bg) == 1 && fomd('bgcode_0') == 0
                fomd('bgcode0_only') = fomd('bg_0'); %MARK
            else
                fomd('bgcode0_only') = nan;
            end
        end

        function fitdata = linpiecewise(~,params,x)
            % params: zeroth index corresponds to the yfit value at x=x0. Next num_knots indices correspond to the knot positions. Next num_knots-1 indices correspond
            % to slopes for the linear regions.  Piecewise linear addition to obtain the value of fitdata at a specific value of x.
            fitdata = ones(size(x))*params(1);
            num_knots = round(length(params)/2);

            for i=2:num_knots
                fitdata = fitdata + params(num_knots+i)*(x>=params(i)).*(min([ones(size(x))*params(i+1),x],[],2) - params(i));
            end
        end

        function logical = identifypeaks(~,data,typ,abs_minallowedslope,max_allowed_2ndderiv)
            condition = (abs(data(typ+'_2ndderiv')) >= max_allowed_2ndderiv) && (abs(data(typ+'_1stderiv')) <= abs_minallowedslope);
            where = condition(condition>0);
            logical = length(where); %  THIS COULD EASILY BE WRONG
            %return length(logical) % > 0? FIX THIS is it numel??
        end

%         function results = residuals(app,params,xdata,ydata)
%             % Division by ydata is to ensure that errors at low absorbance values are given high importance and the ones
%             % at higher absorbance values are given lower importance (since the important region is where the absorbance starts increasing
%             % from low value prior to band gap to higher values...The noise at very high energy levels usually having high absorbance will not
%             % effect the quality of the result a lot)
%             results = sum((abs(ydata-linpiecewise(app,params,xdata))./ydata).^2);
%         end

        function shader(app)
            % Update Fit Limits
            app.UpperFit.Value = app.ShadeArea(1,1);
            app.LowerFit.Value = app.ShadeArea(2,1);

            RGarea = app.ShadeArea;

            % Create new areas
            app.Shade = gobjects(2,1);
            app.Shade(1,1) = rectangle(app.UIAxes_1,"Position", [app.XMin app.YMin abs(RGarea(1,1)-app.XMin) app.YMax-app.YMin],'FaceColor',[0.75 0.75 0.75]);
            app.Shade(2,1) = rectangle(app.UIAxes_1,"Position", [RGarea(2,1) app.YMin abs(app.XMax-RGarea(2,1)) app.YMax-app.YMin],'FaceColor',[0.75 0.75 0.75]);

            % Set default axes boundaries
            app.UIAxes_1.XLim = [app.XMin app.XMax];
            app.UIAxes_1.YLim = [app.YMin app.YMax];

            % Implement drawline for either side of the area
            x = app.XMin;
            y = app.YMin-1;
            w = app.XMax-app.XMin;
            h = (app.YMax*1.01)-(app.YMin-1);

            % Create ROI lines
            app.ROILine = gobjects(2,1);
            app.ROILine(1,1) = images.roi.Line(app.UIAxes_1, "Position", [RGarea(1,1) app.UIAxes_1.YLim(1)-1; RGarea(1,1) app.UIAxes_1.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Left');
            app.ROILine(2,1) = images.roi.Line(app.UIAxes_1, "Position", [RGarea(2,1) app.UIAxes_1.YLim(1)-1; RGarea(2,1) app.UIAxes_1.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Right');

            % Add callbacks for when point moves and is let go
            addlistener(app.ROILine(1,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.ROILine(2,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));

            addlistener(app.ROILine(1,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.ROILine(2,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));

        end

        function ROIChangedCallback(app,src,event)
            switch event.EventName
                case 'MovingROI'
                    if src.Tag == "Left"
                        app.ShadeArea(1,1) = event.CurrentPosition(1);
                        app.LowerFitLimitEditField.Value = event.CurrentPosition(1);
                    else
                        app.ShadeArea(2,1) = event.CurrentPosition(2);
                        app.UpperFitLimitEditField.Value = event.CurrentPosition(2);
                    end

                    % Continue as normal
                    updateAreas(app);
                case 'ROIMoved'
                    if src.Tag == "Left"
                        value = app.LowerFitLimitEditField.Value;

                        % Code to snap to nearest data point
                        app.left_line_index = closest(app,value);
                        close = app.Wavelength(app.left_line_index);

                        app.LowerFitLimitEditField.Value = close;

                        app.ROILine(1,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];
                        app.ShadeArea = [close; app.ShadeArea(2,1)];
                        updateAreas(app);
                    else
                        value = app.UpperFitLimitEditField.Value;

                        % Code to snap to nearest data point
                        app.right_line_index = closest(app,value);
                        close = app.Wavelength(app.right_line_index);

                        app.UpperFitLimitEditField.Value = close;

                        app.ROILine(2,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];
                        app.ShadeArea = [app.ShadeArea(1,1); close];
                        updateAreas(app);
                    end
            end
        end

        function updateAreas(app)
            % Update both shaded areas up until XLims
            RGarea = app.ShadeArea;
            height = app.YMax-app.YMin;

            % Update new areas
            app.Shade(1,1).Position = [app.XMin app.YMin RGarea(1,1)-app.XMin height];
            app.Shade(2,1).Position = [RGarea(2,1) app.YMin app.XMax-RGarea(2,1) height];
        end

        function index = closest(app,value)
            % Code to snap to nearest data point by given index
            [~, index] = min(abs(app.Wavelength - value));
        end

%         function [c,ceq] = nonlcon(~,params,xdata,num_knots,sign,min_knotdist)
%             locs = 2:num_knots+1;
%             c =  params(locs(1:end)+1)*sign-(params(locs(1:end))*sign+min_knotdist);
%             ceq = [params(2); params(num_knots)]-[xdata(1);xdata(end)];
%         end

        function test(app)
            % Load test data and initialize variables
            load TestData.mat UVVISTEST
            energy = UVVISTEST(:,"E");
            da = UVVISTEST(:,"DA");
            ia = UVVISTEST(:,"IA");

            % Create dictionary equivalents with example data
            K = {'lower_wl', 'upper_wl', 'num_knots', 'min_knotdist', 'tol', 'maxtol', 'merge_linsegslopediff_percent', 'max_merge_differentialTP', ...
                'min_allowedslope', 'min_finseglength', 'min_bgTP_diff', 'min_bkgrdslope', 'min_bgbkgrdslopediff', 'min_bgTP_finseg_diff', 'min_bgfinalseglength', ...
                'analysis_types', 'maxbgspersmp', 'chkoutput_types', 'abs_minallowedslope', 'max_absolute_2ndderiv', 'use_absderivs_forpeaks'};
            V = {390, 940, 8, 0.05, 1e-06, 1e-03, 10.0, 10000.0, -2, 0.1, 0.2, -0.05, 0.2, 0.2, 0.2, {'DA','IA'}, 4, {'DA','IA'}, -Inf, Inf, false};
            inputparamsd = containers.Map(K,V);

            x = {'hv', 'DA', 'IA'};
            y = {energy, da, ia};
            data = containers.Map(x, y);

            % Plot normal data
            line(app.UIAxes_2,table2array(energy),table2array(da),'Color','g');
            line(app.UIAxes_3,table2array(energy),table2array(ia),'Color', 'g');

            % Run the app
            [linfitd,fomd,data] = runuvvis(app,data,inputparamsd);

            LINFITDKEYS = keys(linfitd);
            LINFITDKEYS = LINFITDKEYS';
            LINFITDVALS = values(linfitd);
            LINFITDVALS = LINFITDVALS';

            FOMDKEYS = keys(fomd);
            FOMDKEYS = FOMDKEYS';
            FOMDVALS = values(fomd);
            FOMDVALS = FOMDVALS';

            % Fit data from CSV to compare with calculated fit values
            fitDA = table2array(UVVISTEST(:,'fitdata_DA'));
            fitIA = table2array(UVVISTEST(:,'fitdata_IA'));

            % Plot MATLAB fit
            line(app.UIAxes_2,table2array(data('hv')),data('DA_linfit'),'Color','r');
            line(app.UIAxes_3,table2array(data('hv')),data('IA_linfit'),'Color','r');

            app.DABandGapLabel.Text = "DA Bandgap Energy (eV): " + fomd("DA_bg_0");
            app.IABandGapLabel.Text = "IA Bandgap Energy (eV): " + fomd("IA_bg_0");

            app.DACode.Text = "DA FOMD Code(s): " + fomd("DA_bgcode_0");
            app.IACode.Text = "IA FOMD Code(s): " + fomd("IA_bgcode_0");

        end

        function SNG_method(app,Energy,YData)
            % Create "absorption coefficient" equivalent
            TaucY = YData.*Energy;

            % Caculate varying transitions with different exponents
            DA = TaucY.^2;
            normD = max(DA);
            DA = DA./normD;

            IA = TaucY.^(1/2);
            normI = max(IA);
            IA = IA./normI;

            % Plot Tauc data
            line(app.UIAxes_2,Energy,DA);
            line(app.UIAxes_3,Energy,IA);

            % Create dictionary equivalents with example data
            k = {'lower_wl', 'upper_wl', 'num_knots', 'min_knotdist', 'tol', 'maxtol', 'merge_linsegslopediff_percent', 'max_merge_differentialTP', ...
                'min_allowedslope', 'min_finseglength', 'min_bgTP_diff', 'min_bkgrdslope', 'min_bgbkgrdslopediff', 'min_bgTP_finseg_diff', 'min_bgfinalseglength', ...
                'analysis_types', 'maxbgspersmp', 'chkoutput_types', 'abs_minallowedslope', 'max_absolute_2ndderiv', 'use_absderivs_forpeaks'};
            v = {390, 940, 8, 0.05, 1e-06, 1e-03, 5.0, 10000.0, -2, 0.1, 0.2, -0.05, 0.2, 0.2, 0.2, {'DA','IA'}, 4, {'DA','IA'}, -Inf, Inf, false};
            inputparamsd = containers.Map(k,v);

            % Create map of energy and transition data
            x = {'hv', 'DA', 'IA'};
            y = {Energy, DA, IA};
            data = containers.Map(x, y);

            % Pass into fitting
            try
                [linfitd,fomd,data] = runuvvis(app,data,inputparamsd); % THIS RUNS ALL OTHER FUNCTIONS (fitresult, calc_bandgap, ...etc)
            catch e
                disp(e);
                return
            end

            LINFITDKEYS = keys(linfitd);
            LINFITDKEYS = LINFITDKEYS';
            LINFITDVALS = values(linfitd);
            LINFITDVALS = LINFITDVALS';

            FOMDKEYS = keys(fomd);
            FOMDKEYS = FOMDKEYS';
            FOMDVALS = values(fomd);
            FOMDVALS = FOMDVALS';

            DATAKEYS = keys(data);
            DATAKEYS = DATAKEYS';
            DATAVALS = values(data);
            DATAVALS = DATAVALS';

            DA_bg = fomd("DA_bg_0");
            IA_bg = fomd("IA_bg_0");
            
            if isnan(DA_bg)
                DA_bg = "NaN";
            end

            if isnan(IA_bg)
                IA_bg = "NaN";
            end
            
            app.DABandGapLabel.Text = "DA Bandgap Energy (eV): " + DA_bg;
            app.IABandGapLabel.Text = "IA Bandgap Energy (eV): " + IA_bg;

            app.DACode.Text = "DA FOMD Code(s): " + fomd("DA_bgcode_0");
            app.IACode.Text = "IA FOMD Code(s): " + fomd("IA_bgcode_0");

            % Plot accompanying fit data after calculated
            line(app.UIAxes_2,data('hv'),data('DA_linfit'),'Color','r');
            line(app.UIAxes_3,data('hv'),data('IA_linfit'),'Color','r');
        end

        function mean_band_gaps = segmentation_method(app,energy,absorption_coefficient,bg_type,show_graph)
            show_graph = true;

            % Use bg_type to determine axes
            if bg_type == "direct"
                ax = app.UIAxes_2;
            elseif bg_type == "indirect"
                ax = app.UIAxes_3;
            end
            
            % Normalize absorption coeff
            norm = max(absorption_coefficient);
            direct_abs_corrected = absorption_coefficient./norm;

            if show_graph == true
                line(ax,energy,direct_abs_corrected);
            end

            first_deriv = diff(direct_abs_corrected)./diff(energy);
            first_deriv(end+1) = first_deriv(end); %Add copy of end for size

            energy_deriv = energy;

            first_deriv_savgol_filt = sgolayfilt(first_deriv,4,25);

            energy_negats_elim = energy(first_deriv_savgol_filt > mean(first_deriv_savgol_filt));
            first_deriv_negats_elim = direct_abs_corrected(first_deriv_savgol_filt > mean(first_deriv_savgol_filt));

            E = energy_negats_elim(energy_negats_elim < mean(energy_negats_elim)+2*std(energy_negats_elim));
            EA = first_deriv_negats_elim(energy_negats_elim < mean(energy_negats_elim)+2*std(energy_negats_elim));


            new_energy = E;
            new_absorption_direct = EA;
            spring_quotient = 1.0; %USE THIS TO ALTER THE NUMBER OF LINES SELECTED!

            try
                position_linregress_vals_10_role = [];
                s = 1:round(length(new_energy)/10):length(new_energy);

                for k = 1:length(s)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(s(k):s(k+1)),new_absorption_direct(s(k):s(k+1)));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_10_role(end+1,:) = [s(k),s(k+1),slope,intercept,R_squared,x_intercept];
                    end
                end

                position_linregress_vals_10_role = sortrows(position_linregress_vals_10_role,3);

                for h = size(position_linregress_vals_10_role,1)
                    if position_linregress_vals_10_role(h,3) < (position_linregress_vals_10_role(end,3))/(spring_quotient)
                        position_linregress_vals_10_role(h,:) = [];
                    end
                end
            catch ValueError
                disp(ValueError);
                position_linregress_vals_10_role = [];
            end

            try
                position_linregress_vals_9_role = [];
                r = 1:round(length(new_energy)/9):length(new_energy);

                for k = 1:length(r)-1
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(r(k):r(k+1)),new_absorption_direct(r(k):r(k+1)));
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_9_role(end+1,:) = [r(k),r(k+1),slope,intercept,R_squared,x_intercept];
                    end
                end

                position_linregress_vals_9_role = sortrows(position_linregress_vals_9_role,3);

                for h = size(position_linregress_vals_9_role,1)
                    if position_linregress_vals_9_role(h,3) < (position_linregress_vals_9_role(end,3))/(spring_quotient)
                        position_linregress_vals_9_role(h,:) = [];
                    end
                end

            catch ValueError
                disp(ValueError);
                position_linregress_vals_9_role = [];
            end

            try
                position_linregress_vals_8_role = [];
                r = 1:round(length(new_energy)/8):length(new_energy);

                for k = 1:length(r)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(r(k):r(k+1)),new_absorption_direct(r(k):r(k+1)));%(r(k):r(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_8_role(end+1,:) = [r(k),r(k+1),slope,intercept,R_squared,x_intercept]; %tuple([r(k),r(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_8_role = sortrows(position_linregress_vals_8_role,3);

                for h = size(position_linregress_vals_8_role,1)
                    if position_linregress_vals_8_role(h,3) < (position_linregress_vals_8_role(end,3))/(spring_quotient)
                        position_linregress_vals_8_role(h,:) = [];
                    end
                end

            catch ValueError
                disp(ValueError);
                position_linregress_vals_8_role = [];
            end

            try
                position_linregress_vals_7_role = [];
                t = 1:round(length(new_energy)/7):length(new_energy);

                for k = 1:length(t)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)));%(t(k):t(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_7_role(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept];%tuple([t(k),t(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_7_role = sortrows(position_linregress_vals_7_role,3);

                for h = size(position_linregress_vals_7_role,1)
                    if position_linregress_vals_7_role(h,3) < (position_linregress_vals_7_role(end,3))/(spring_quotient)
                        position_linregress_vals_7_role(h,:) = [];
                    end
                end

            catch ValueError
                disp(ValueError);
                position_linregress_vals_7_role = [];
            end

            try
                position_linregress_vals_6_role = [];
                t = 1:round(length(new_energy)/6):length(new_energy);
                for k = 1:length(t)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)));%(t(k):t(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared < 0.75
                        continue
                    else
                        position_linregress_vals_6_role(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept];%tuple([t(k),t(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_6_role = sortrows(position_linregress_vals_6_role,3);

                for h = size(position_linregress_vals_6_role,1)
                    if position_linregress_vals_6_role(h,3) < (position_linregress_vals_6_role(end,3))/(spring_quotient)
                        position_linregress_vals_6_role(h,:) = [];
                    end
                end
            catch ValueError
                disp(ValueError);
                position_linregress_vals_6_role = [];
            end

            try
                position_linregress_vals_5_role = [];
                t = 1:round(length(new_energy)/5):length(new_energy);
                for k = 1:length(t)-1 %-1?
                    [slope, intercept, R_squared, x_intercept] = linregress(app,new_energy(t(k):t(k+1)),new_absorption_direct(t(k):t(k+1)));%(t(k):t(k+1) + 1)??
                    if slope < 0 || x_intercept < 0 || x_intercept > 10 || R_squared <  0.75
                        continue
                    else
                        position_linregress_vals_5_role(end+1,:) = [t(k),t(k+1),slope,intercept,R_squared,x_intercept];%tuple([t(k),t(k+1),slope,intercept,R_squared,x_intercept])
                    end
                end

                position_linregress_vals_5_role = sortrows(position_linregress_vals_5_role,3);

                for h = size(position_linregress_vals_5_role,1)
                    if position_linregress_vals_5_role(h,3) < (position_linregress_vals_5_role(end,3))/(spring_quotient)
                        position_linregress_vals_5_role(h,:) = [];
                    end
                end

            catch ValueError
                disp(ValueError);
                position_linregress_vals_5_role = [];
            end

            try
                band_gaps_list = [position_linregress_vals_5_role(end,6);
                    position_linregress_vals_6_role(end,6);
                    position_linregress_vals_7_role(end,6);
                    position_linregress_vals_8_role(end,6);
                    position_linregress_vals_9_role(end,6);
                    position_linregress_vals_10_role(end,6)];

                mean_band_gaps = mean(band_gaps_list);
                std_band_gaps = std(band_gaps_list);

%                 remove_list = [];
                for h = 1:size(band_gaps_list,1)
                    if band_gaps_list(h,1) > (mean_band_gaps + 2*std_band_gaps) || band_gaps_list(h,1) < (mean_band_gaps - 2*std_band_gaps)
                        band_gaps_list(h) = [];
                    end
                end

%                 band_gaps_list(remove_list) = [];

                mean_band_gaps = mean(band_gaps_list);
                std_band_gaps = std(band_gaps_list);

                x = linspace(min(new_energy),max(new_energy),1000);
                x = x';

                if show_graph == true
                    h = position_linregress_vals_5_role(end,:);
                    line(ax,x,x*h(3)+h(4),'Color','g');

                    h = position_linregress_vals_6_role(end,:);
                    line(ax,x,x*h(3)+h(4),'Color','c');

                    h = position_linregress_vals_7_role(end,:);
                    line(ax,x,x*h(3)+h(4),'Color','m');

                    h = position_linregress_vals_8_role(end,:);
                    line(ax,x,x*h(3)+h(4),'Color','yellow');

                    h = position_linregress_vals_9_role(end,:);
                    line(ax,x,x*h(3)+h(4),'Color','magenta');

                    h = position_linregress_vals_10_role(end,:);
                    line(ax,x,x*h(3)+h(4),'Color','r');

                    axis(ax,[min(energy),max(energy),0,1]);
                end

            catch ValueError
                disp(ValueError);
                disp("Empty Sequence!");
                return
            end

            if bg_type == "direct"
                app.DABandGapLabel.Text = "DA Bandgap Energy (eV): " + mean_band_gaps;
            elseif bg_type == "indirect"
                app.IABandGapLabel.Text = "IA Bandgap Energy (eV): " + mean_band_gaps;
            end
        end

        function bandgap = mars_method(app,energy,absorption_coefficient,bg_type,show_graph)
            show_graph = true;

            % Use bg_type to determine axes
            if bg_type == "direct"
                ax = app.UIAxes_2;
            elseif bg_type == "indirect"
                ax = app.UIAxes_3;
            end

            % Normalize absorption coeff data
            norm = max(absorption_coefficient);
            direct_abs_corrected = absorption_coefficient./norm;
            
            try
                trainParams = aresparams2('cubic',false,'maxFuncs',[],'useMinSpan',-1,'useEndSpan',-1,'threshold',1e-5,'prune',true); % Set parameters other than default

                [model, ~, ~] = aresbuild(energy,direct_abs_corrected,trainParams);
                aresinfo(model,energy,direct_abs_corrected); %Extra info like (Verbose=2)
            catch ValueError
                disp(ValueError);
                uialert(app.UIFigure,"Problem in MARS fitting parameters!","MARS Problem","Icon","Error");
                return
            end

            energy_elbows = [min(energy); max(energy)];

            basislist = areseq(model);%,[],[],[],[],"hide"); %<-- for linear when cubic % basislist = [model.basis_];
            strarray = string(basislist(1:end-1));
            sz = size(strarray,1);
            for i = 1:sz
                try
                    str = strarray(i); % Iterate through each basis eqn
                    out = regexp(str,"\d+\.\d+","match");
%                     pieces = strsplit(str,["x1 ",")"]);

%                     For piecewise-cubic|MAYBE ADD IF ELSE FOR THESE CASES
%                     pieces = strsplit(str,[",","|"]); % Split into separate pieces
%                     sign = str2double(pieces(2)); % Extract sign
%                     lin_num = str2double(pieces(4)); % Extract linear coefficient
%                     new = sign*lin_num; % Multiply by sign to get proper new elbow num

                    new = str2double(out);
                    if new ~= energy_elbows
                        energy_elbows = [energy_elbows;new];
                    end

                    % Split the equations at the equal sign and take expr
                    eqn = strsplit(str, " = ");
                    basis_funcs = eqn(2);

                    BF{i,1} = basis_funcs;
                catch IndexError
                    disp(IndexError);
                    disp(strarray(i));
                    continue
                end
            end

            [y_hat, ~] = arespredict(model,energy);

            if show_graph == true
                % aresplot(model);
                scatter(ax,energy,direct_abs_corrected,10,"black","filled","o");
                line(ax,energy,y_hat, "Color",'b','Marker',".");
            end

            % Split final expression in basislist to get end function
            last = strsplit(string(basislist(end))," = ");
            export = last(2);

            % Replace each BF with actual basis function
            for i = 1:sz
                % Add this condition so that last element is replaced w/o space
                if i ~= sz
                    old = strcat("BF",num2str(i)," ");
                else
                    old = strcat("BF",num2str(i));
                end
                export = strrep(export,old,BF{i,1});
            end
            
            % Create exported expression into function
            lastfunc = str2func(['@(x1)',convertStringsToChars(export)]);
            direct_abs_elbows = feval(lastfunc,energy_elbows);

            elbows_list = [];
            elbows_list(:,1) = energy_elbows;
            elbows_list(:,2) = direct_abs_elbows;

            % Sort
            elbows_list = sortrows(elbows_list);

            lin_segs = [];

            for point = 1:size(elbows_list,1)-1
                que = [];
                for i = 1:size(energy,1)
                    if energy(i,1) > elbows_list(point,1) && energy(i,1) < elbows_list(point+1,1)
                        que = [que;energy(i,1)]; %#ok<AGROW> 
                    end
                end

                num_pts = size(que,1);

                x_length = elbows_list(point+1,1)-elbows_list(point,1);
                length = ((elbows_list(point+1,1)-elbows_list(point,1))^2 + (elbows_list(point+1,2)-elbows_list(point,2))^2)^(1/2);
                slope = (elbows_list(point+1,2)-elbows_list(point,2))/(elbows_list(point+1,1)-elbows_list(point,1));
                y_intercept = elbows_list(point+1,2)-(slope*elbows_list(point+1,1));

                x_intercept = (-1*y_intercept)/slope;
                weighting_factor = (slope^2)*(x_length^(-2))*(abs(length)^(1/2))*num_pts; %x_length*2 from python??. Not what is stated in paper

                try
                    if x_intercept > 0 && slope > 0 && num_pts > 10
                        lin_segs = [lin_segs;[x_length,length,slope,y_intercept,x_intercept,weighting_factor]];
                    end
                catch TypeError
                    disp(TypeError);
                    disp("Weird complex zoo error..");
                    continue
                end
            end

            lin_segs = sortrows(lin_segs,6); % Sort by weighting factor
            winner = lin_segs(end,:); % Choose max weighting_factor

            adj_energy = linspace(min(energy),max(energy),1000);
            adj_energy = adj_energy';

            adj_winner = (adj_energy*winner(3))+winner(4);

            if show_graph == true
                line(ax,adj_energy,adj_winner,"Color",'r'); % Tau_Segment, add handle?
                axis(ax,[min(energy),max(energy),0,1]);
            end

            bandgap = winner(5);

            if bg_type == "direct"
                app.DABandGapLabel.Text = "DA Bandgap Energy (eV): " + bandgap;
            elseif bg_type == "indirect"
                app.IABandGapLabel.Text = "IA Bandgap Energy (eV): " + bandgap;
            end
        end
    
        function calculate_band_gap(app,energy,absorption_coefficient,method)
            show_graph = true;
            absorption_coeff_savgol_filt = sgolayfilt(absorption_coefficient,4,25);

            for bg_type = ["direct", "indirect"]
                if bg_type == "direct"
                    absorption_y(:,1) = (abs(absorption_coeff_savgol_filt).*energy).^2;
                elseif bg_type == "indirect"
                    absorption_y(:,1) = (abs(absorption_coeff_savgol_filt).*energy).^(1/2);
                elseif bg_type == "log10"
                    absorption_y(:,1) = log10(abs(absorption_coeff_savgol_filt))-3;
                elseif bg_type == "raw_alpha"
                    absorption_y(:,1) = abs(absorption_coeff_savgol_filt);
                else
                    uialert(app.TaucPlotFittingUIFigure,"Not a valid band gap type.","Type Error","Icon","error");
                end
            

                if method == "SNG"
                    SNG_method(app,energy,absorption_coeff_savgol_filt);
                    break
                elseif method == "Segmentation"
                    segmentation_method(app,energy,absorption_y,bg_type,show_graph);
                elseif method == "MARS"
                    mars_method(app,energy,absorption_y,bg_type,show_graph);
                else
                    uialert(app.TaucPlotFittingUIFigure,"Not a valid band gap methodology","Method Error","Icon","error");
                end
            end
        end
        
        function [slope, intercept, R_squared, x_intercept] = linregress(~,x,y)
%             This function computes a basic linear regression when given x and y data.
%             It returns the slope, intercept, R-squared value, and x_intercept.
%             For further information on an alternative, see sklearn.linear_model.LinearRegression.

            if length(x) ~= length(y)
                disp("Error: Lists must be the same size!")
                return
            end
            x_squared=[];
            x_times_y=[];
            for i = 1:length(x)
                x_squared(end+1) = x(i)^2;
                x_times_y(end+1) = x(i)*y(i);
            end

            intercept = (sum(y)*sum(x_squared)-sum(x)*sum(x_times_y))/(length(x)*sum(x_squared)-sum(x)^2);
            slope = (length(x)*sum(x_times_y)-sum(x)*sum(y))/(length(x)*sum(x_squared)-sum(x)^2);
            %a+bx=y Where a is intercept and b is slope
            %Calculate the R**2 value through the formula 1-(SSres/SStot)
            y_mean = mean(y);
            y_res = [];
            y_tot = [];

            for i = 1:length(y)
                modeled_pt = slope*x(i)+intercept;
                y_res(end+1) = (modeled_pt-y_mean)^2;
                y_tot(end+1) = (y(i)-y_mean)^2;
            end

            R_squared = sum(y_res)/sum(y_tot);
            % y_diff_squared=[]
            % for i in y:
            %    y_diff_squared.append((i-y_mean)**2)
            % SStot=sum(y_diff_squared)
            % y_diff_func_squared=[]
            % for i in y:
            %    y_diff_func_squared.append((i-(slope*i+intercept))**2)
            % SSres=sum(y_diff_func_squared)    
            % R_squared=1-(SSres/SStot)
            x_intercept = -intercept/slope;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             test(app)
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;

            % Definitions of possible bg_code0 values
            code0 = 'Successful assignment of bandgap linear segment using simple rules';
            code1 = 'Linear segment with a slope less than min_slope was found';
            code2 = 'Succesful assignment of bandgap linear segment using a slightly higher slope at following segment criterion but bgdiff > min in current segment; exists only the older versions and is obsolete from 3/6/16';
            code3 = 'No linear segment was observed for band gap';
            code4 = 'Band gap linear segment(s) deleted due to inability to identify background linear segment with sufficient difference in slope';
            code5 = 'All Band gap lin segs deleted due to inability to identify background linear segment with sufficient difference in slope';
            code6 = 'Final segment has slope higher than previous and explains bgdiff>min this check occurs only when no band gap has been found with other criteria above';
            % There is a chance that you are underestimating band gaps
            code7 = 'Peaks were found';
            code8 = 'NaNs were found in the absorption spectrum';
            code9 = 'Linear fitting failed';

            app.UITable.Data = ["Code 0",code0;"Code 1",code1;"Code 2",code2;"Code 3",code3;"Code 4",code4;"Code 5",code5;"Code 6",code6;"Code 7",code7;"Code 8",code8;"Code 9",code9;];
        end

        % Button pushed function: LoadAbsDataButton
        function LoadAbsDataButtonPushed(app, event)
            app.LoadAbsDataButton.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [File, FilePath] = uigetfile('*.csv;*.xlsx');
            app.LoadCreateError.Text = "Absorption: " + File;
            app.LoadCreateError.FontColor = [0.15,0.15,0.15];

            try
                % Read the data and copy to public property to use
                %                 opts = detectImportOptions(fullfile(FilePath, File));
                %                 opts.VariableNamesLine = 1;
                temp = readtable(fullfile(FilePath, File));

                % Check if MATLAB addons are installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
                matlab.addons.isAddonEnabled('Curve Fitting Toolbox');
            catch ERROR
                switch ERROR.identifier
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install the 'Image Processing' or 'Curve Fitting' Toolboxes to continue";
                        app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                        app.LoadAbsDataButton.Enable = 'on';
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                        app.LoadAbsDataButton.Enable = 'on';
                        return;
                end
            end
            app.LoadAbsDataButton.Enable = 'on';
            app.UIAxes_1.YLabel.String = "Intensity";
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;

            % Clear all previous properties and reset to default values
            clearProperties(app);

            % Set app properties from table data as array variables
            firstColumn = table2array(temp(:,1));
            secondColumn = table2array(temp(:,2));

            % If wavelength is increasing, energy will be flipped and will flip knots/slopes
            if all(diff(firstColumn) >=0 )
                app.Wavelength = flipud(firstColumn);
                app.Absorption = flipud(secondColumn);
            else
                app.Wavelength = firstColumn;
                app.Absorption = secondColumn;
            end

            % Smoothing & filtering of ydata
%             SmoothYDataSwitchValueChanged(app);

            % Normalize ydata
            normY = max(app.Absorption);
            app.Absorption = app.Absorption/normY;

            % Draw the absorption spectra plot next
            app.absp = line(app.UIAxes_1,app.Wavelength,app.Absorption);

            % Find center of x data to use as refernece for initial ROI
            % line positions
            CenterLambda = median(app.Wavelength);

            % Set max and min data for both x and y properties
            app.XMin = min(app.Wavelength);
            app.XMax = max(app.Wavelength);
            app.YMin = min(app.Absorption);
            app.YMax = max(app.Absorption);

            Width_1 = (CenterLambda-app.XMin);
            Width_2 = (app.XMax - CenterLambda);

            app.ShadeArea = [app.XMin + Width_1/2; CenterLambda + Width_2/2];

            app.left_line_index = closest(app,app.ShadeArea(1,1));
            app.right_line_index = closest(app,app.ShadeArea(2,1));

            % Set GUI elements to the where ROI lines start
            app.LowerFitLimitEditField.Value = app.Wavelength(app.left_line_index);
            app.UpperFitLimitEditField.Value = app.Wavelength(app.right_line_index);

            % Draw shaded rectangles with ROI lines first
            shader(app);

            % Make sure line is ontop of shade
            uistack(app.absp,"top");
        end

        % Button pushed function: LoadReflectanceDataButton
        function LoadReflectanceDataButtonPushed(app, event)
            app.LoadReflectanceDataButton.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [File, FilePath] = uigetfile('*.csv;*.xlsx');
            app.LoadCreateError.Text = "Reflectance: " + File;
            app.LoadCreateError.FontColor = [0.15,0.15,0.15];

            try
                % Read the data and copy to public property to use
                %                 opts = detectImportOptions(fullfile(FilePath, File));
                %                 opts.VariableNamesLine = 1;
                temp = readtable(fullfile(FilePath, File));

                % Check if MATLAB addons are installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
                matlab.addons.isAddonEnabled('Curve Fitting Toolbox');
            catch ERROR
                switch ERROR.identifier
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install the 'Image Processing' or 'Curve Fitting' Toolboxes to continue";
                        app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                        app.LoadReflectanceDataButton.Enable = 'on';
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        app.LoadCreateError.FontColor = [1.00,0.00,0.00];
                        app.LoadReflectanceDataButton.Enable = 'on';
                        return;
                end
            end
            app.LoadReflectanceDataButton.Enable = 'on';
            app.UIAxes_1.YLabel.String = "f(R)";
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;

            % Clear all previous properties and reset to default values
            clearProperties(app);

            % Set app properties from table data as array variables
            firstColumn = table2array(temp(:,1));
            secondColumn = table2array(temp(:,2));

            % If wavelength is increasing, energy will be flipped and will flip knots/slopes
            if all(diff(firstColumn) >=0 )
                app.Wavelength = flipud(firstColumn);
                DR = flipud(secondColumn)/100;
            else
                app.Wavelength = firstColumn;
                DR = secondColumn/100;
            end

            app.OriginalAbs = (1-DR.^2)./(2*DR);

            % Smoothing & filtering of ydata
            SmoothYDataSwitchValueChanged(app);

            % Normalize ydata
            normY = max(app.Absorption);
            app.Absorption = app.Absorption/normY;

             % Draw the absorption spectra plot next
            app.absp = line(app.UIAxes_1,app.Wavelength,app.Absorption);

            % Find center of x data to use as refernece for initial ROI
            % line positions
            CenterLambda = median(app.Wavelength);

            % Set max and min data for both x and y properties
            app.XMin = min(app.Wavelength);
            app.XMax = max(app.Wavelength);
            app.YMin = min(app.Absorption);
            app.YMax = max(app.Absorption);

            Width_1 = (CenterLambda-app.XMin);
            Width_2 = (app.XMax - CenterLambda);

            app.ShadeArea = [app.XMin + Width_1/2; CenterLambda + Width_2/2];

            app.left_line_index = closest(app,app.ShadeArea(1,1));
            app.right_line_index = closest(app,app.ShadeArea(2,1));

            % Set GUI elements to the where ROI lines start
            app.LowerFitLimitEditField.Value = app.Wavelength(app.left_line_index);
            app.UpperFitLimitEditField.Value = app.Wavelength(app.right_line_index);

            % Draw shaded rectangles with ROI lines first
            shader(app);

            % Make sure line is ontop of shade
            uistack(app.absp,"top");
        end

        % Button pushed function: StartFitButton
        function StartFitButtonPushed(app, event)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)

            % Cut wavelength and abs data to the specified fit limits
            if app.left_line_index < app.right_line_index
                XData = app.Wavelength(app.left_line_index:app.right_line_index)*1e-9;
                YData = app.Absorption(app.left_line_index:app.right_line_index);
            else % This is if wavelength starts from highest value
                XData = app.Wavelength(app.right_line_index:app.left_line_index)*1e-9;
                YData = app.Absorption(app.right_line_index:app.left_line_index);
            end

            % Define constants and calculate energy in joules
            h = 6.62607015e-34;
            c = 299792458;
            joules = (h*c)./XData;

            % Convert energy into eV and calculate (a*hv)
            Energy = joules*6.242e18;
            
            % Run code to choose method and calculate Tauc YData
            method = app.ButtonGroup.SelectedObject.Text;
            calculate_band_gap(app,Energy,YData,method)
        end

        % Value changed function: UpperFitLimitEditField
        function UpperFitLimitEditFieldValueChanged(app, event)
            value = app.UpperFitLimitEditField.Value;

            % Keep the changed input within the boundary conditons
            if (value > app.XMax)
                value = app.XMax;
                app.UpperFitLimitEditField.Value = value;
            elseif (value < app.ROILine(1,1).Position(1))
                uialert(app.TaucPlotFittingUIFigure,"Cannot input a limit past other ROI line","Input Error","Icon","Error");
                app.UpperFitLimitEditField.Value = event.PreviousValue;
                return
            end

            app.right_line_index = closest(app,value);
            close = app.Wavelength(app.right_line_index);

            app.ROILine(2,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];

            app.ShadeArea = [app.ShadeArea(1,1); close];
            updateAreas(app);
        end

        % Value changed function: LowerFitLimitEditField
        function LowerFitLimitEditFieldValueChanged(app, event)
            value = app.LowerFitLimitEditField.Value;

            % Keep the changed input within the boundary conditons
            if (value < app.XMin)
                value = app.XMin;
                app.LowerFitLimitEditField.Value = value;
            elseif (value > app.ROILine(2,1).Position(1))
                uialert(app.TaucPlotFittingUIFigure,"Cannot input a limit past other ROI line","Input Error","Icon","Error");
                app.LowerFitLimitEditField.Value = event.PreviousValue;
                return
            end

            % Code to snap to nearest data point
            app.left_line_index = closest(app,value);
            close = app.Wavelength(app.left_line_index);

            app.ROILine(1,1).Position = [close app.UIAxes_1.YLim(1)-1; close app.UIAxes_1.YLim(2)*1.01];

            app.ShadeArea = [close; app.ShadeArea(2,1)];
            updateAreas(app);
        end

        % Callback function
        function SmoothYDataSwitchValueChanged(app, event)
%             value = app.SmoothYDataSwitch.Value;
%             
%             if value == "On"
%                 app.Absorption = smooth(app.OriginalAbs,'sgolay',4);
% 
%                 % Normalize ydata
%                 normY = max(app.Absorption);
%                 app.Absorption = app.Absorption/normY;
% 
%                 app.smoothed = line(app.UIAxes_1,app.Wavelength,app.Absorption,'Color','r');
%             else
%                 app.Absorption = app.OriginalAbs;
% 
%                 % Normalize ydata
%                 normY = max(app.Absorption);
%                 app.Absorption = app.Absorption/normY;
% 
%                 delete(app.smoothed)
%             end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton.Text == "SNG"
                app.GoodnessofFitFOMDCodesLabel.Visible = true;
                app.UITable.Visible = true;
                app.DACode.Visible = true;
                app.IACode.Visible = true;
            else
                app.GoodnessofFitFOMDCodesLabel.Visible = false;
                app.UITable.Visible = false;
                app.DACode.Visible = false;
                app.IACode.Visible = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TaucPlotFittingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {640, 640, 640};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {640, 640};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 308};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TaucPlotFittingUIFigure = uifigure('Visible', 'off');
            app.TaucPlotFittingUIFigure.Name = 'Tauc Plot Fitting';
            app.TaucPlotFittingUIFigure.Icon = 'logo.png';
            app.TaucPlotFittingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TaucPlotFittingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadAbsDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadAbsDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAbsDataButtonPushed, true);
            app.LoadAbsDataButton.Text = 'Load Abs Data';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.StartFitButton = uibutton(app.GridLayout2, 'push');
            app.StartFitButton.ButtonPushedFcn = createCallbackFcn(app, @StartFitButtonPushed, true);
            app.StartFitButton.Text = 'Start Fit';
            app.LoadReflectanceDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadReflectanceDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReflectanceDataButtonPushed, true);
            app.LoadReflectanceDataButton.Text = 'Load Reflectance Data';
            app.DABandGapLabel = uilabel(app.GridLayout2);
            app.DABandGapLabel.Text = 'DA Bandgap Energy (eV):';
            app.DACode = uilabel(app.GridLayout2);
            app.DACode.Text = 'DA FOMD Code(s):';
            app.IABandGapLabel = uilabel(app.GridLayout2);
            app.IABandGapLabel.Text = 'IA Bandgap Energy (eV):';
            app.IACode = uilabel(app.GridLayout2);
            app.IACode.Text = 'IA FOMD Code(s):';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes_3 = uiaxes(app.GridLayout3);
            title(app.UIAxes_3, 'Tauc Plot for Indirect Transitions')
            xlabel(app.UIAxes_3, 'Energy (eV)')
            ylabel(app.UIAxes_3, '(\alphahv)^{1/2}')
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_2, 'Tauc Plot for Direct Transitions')
            xlabel(app.UIAxes_2, 'Energy (eV)')
            ylabel(app.UIAxes_2, '(\alphahv)^{2}')
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_1, 'Absorption Spectra')
            xlabel(app.UIAxes_1, 'Wavelength (nm)')
            ylabel(app.UIAxes_1, 'Intensity')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.RightPanel);
            app.FittingMethodLabel = uilabel(app.GridLayout6);
            app.FittingMethodLabel.Text = 'Fitting Method';
            app.ButtonGroup = uibuttongroup(app.GridLayout6);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SNGButton = uiradiobutton(app.ButtonGroup);
            app.SNGButton.Text = 'SNG';
            app.SNGButton.Value = true;
            app.MARSButton = uiradiobutton(app.ButtonGroup);
            app.MARSButton.Text = 'MARS';
            app.SegmentationButton = uiradiobutton(app.ButtonGroup);
            app.SegmentationButton.Text = 'Segmentation';
            app.AbsorptionCutoffLimitsLabel = uilabel(app.GridLayout6);
            app.AbsorptionCutoffLimitsLabel.Text = {'Absorption Cutoff Limits'; ''};
            app.GridLayout5 = uigridlayout(app.GridLayout6);
            app.LowerFitLimitEditFieldLabel = uilabel(app.GridLayout5);
            app.LowerFitLimitEditFieldLabel.Text = 'Lower Fit Limit';
            app.LowerFitLimitEditField = uieditfield(app.GridLayout5, 'numeric');
            app.LowerFitLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFitLimitEditFieldValueChanged, true);
            app.UpperFitLimitEditFieldLabel = uilabel(app.GridLayout5);
            app.UpperFitLimitEditFieldLabel.Text = 'Upper Fit Limit';
            app.UpperFitLimitEditField = uieditfield(app.GridLayout5, 'numeric');
            app.UpperFitLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFitLimitEditFieldValueChanged, true);
            app.GoodnessofFitFOMDCodesLabel = uilabel(app.GridLayout6);
            app.GoodnessofFitFOMDCodesLabel.Text = 'Goodness of Fit (FOMD) Codes';
            app.UITable = uitable(app.GridLayout6);
            app.UITable.ColumnName = {'Code'; 'Description'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.TaucPlotFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UniversalTaucPlot

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TaucPlotFittingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TaucPlotFittingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TaucPlotFittingUIFigure)
        end
    end
end